classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        DrawletterButton             matlab.ui.control.Button
        TrainednetworksListBoxLabel  matlab.ui.control.Label
        TrainednetworksListBox       matlab.ui.control.ListBox
        ClassificationLabel          matlab.ui.control.Label
        ALabel                       matlab.ui.control.Label
        PathtoimageEditFieldLabel    matlab.ui.control.Label
        PathtoimageEditField         matlab.ui.control.EditField
        NewnetworkPanel              matlab.ui.container.Panel
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        TrainingfunctionKnobLabel    matlab.ui.control.Label
        TrainingfunctionKnob         matlab.ui.control.DiscreteKnob
        TrainButton                  matlab.ui.control.Button
        ActivationfunctionKnobLabel  matlab.ui.control.Label
        ActivationfunctionKnob       matlab.ui.control.DiscreteKnob
        DatasetEditFieldLabel        matlab.ui.control.Label
        DatasetEditField             matlab.ui.control.EditField
        LayersEditFieldLabel         matlab.ui.control.Label
        LayersEditField              matlab.ui.control.EditField
        TrainEditFieldLabel          matlab.ui.control.Label
        TrainEditField               matlab.ui.control.EditField
        ValidationEditFieldLabel     matlab.ui.control.Label
        ValidationEditField          matlab.ui.control.EditField
        TestEditFieldLabel           matlab.ui.control.Label
        TestEditField                matlab.ui.control.EditField
        ClassifyButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentNetwork % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        set(app.UIAxes,'Color','black');

        end

        % Button pushed function: DrawletterButton
        function DrawletterButtonPushed(app, event)
            cla(app.UIAxes);
            a = drawfreehand(app.UIAxes);
            a.LineWidth = 5;
            a.Color = 'white';
            a.InteractionsAllowed = 'none';
            a.FaceAlpha = 0;
            a.FaceSelectable = false;
            a.Closed = 0;
            
            b = a.Position
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            dataSet = app.DatasetEditField.Value
            [inputs, targets] = letter_functions.readDataSet(dataSet);
            
            app.currentNetwork = letter_functions.createNet(app.LayersEditField.Value);
            app.currentNetwork = letter_functions.selectActivation(app.currentNetwork, app.ActivationfunctionKnob.Value);
            app.currentNetwork = letter_functions.selectTraining(app.currentNetwork, app.TrainingfunctionKnob.Value);
            app.currentNetwork = letter_functions.segmentData(app.currentNetwork, app.TrainEditField.Value, app.ValidationEditField.Value, app.TestEditField.Value);
            app.currentNetwork = letter_functions.trainFunction(app.currentNetwork, inputs, targets);
            a = app.currentNetwork
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Letter')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DrawletterButton = uibutton(app.UIFigure, 'push');
            app.DrawletterButton.ButtonPushedFcn = createCallbackFcn(app, @DrawletterButtonPushed, true);
            app.DrawletterButton.Text = {'Draw letter'; ''};
            app.TrainednetworksListBoxLabel = uilabel(app.UIFigure);
            app.TrainednetworksListBoxLabel.Text = 'Trained networks';
            app.TrainednetworksListBox = uilistbox(app.UIFigure);
            app.ClassificationLabel = uilabel(app.UIFigure);
            app.ClassificationLabel.Text = 'Classification:';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.PathtoimageEditFieldLabel = uilabel(app.UIFigure);
            app.PathtoimageEditFieldLabel.Text = 'Path to image:';
            app.PathtoimageEditField = uieditfield(app.UIFigure, 'text');
            app.NewnetworkPanel = uipanel(app.UIFigure);
            app.NewnetworkPanel.Title = 'New network';
            app.NameEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.NameEditField.Value = 'network1';
            app.TrainingfunctionKnobLabel = uilabel(app.NewnetworkPanel);
            app.TrainingfunctionKnobLabel.Text = 'Training function';
            app.TrainingfunctionKnob = uiknob(app.NewnetworkPanel, 'discrete');
            app.TrainingfunctionKnob.Items = {'trainlm', 'trainbfg', 'trainbr', 'traingda', 'trainr'};
            app.TrainingfunctionKnob.Value = 'trainlm';
            app.TrainButton = uibutton(app.NewnetworkPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ActivationfunctionKnobLabel = uilabel(app.NewnetworkPanel);
            app.ActivationfunctionKnobLabel.Text = 'Activation function';
            app.ActivationfunctionKnob = uiknob(app.NewnetworkPanel, 'discrete');
            app.ActivationfunctionKnob.Items = {'tansig', 'purelin', 'hardlim', 'poslin', 'logsig'};
            app.ActivationfunctionKnob.Value = 'tansig';
            app.DatasetEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.DatasetEditFieldLabel.Text = 'Data set';
            app.DatasetEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.DatasetEditField.Value = 'set_2';
            app.LayersEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.LayersEditFieldLabel.Text = 'Layers';
            app.LayersEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.LayersEditField.Value = '10';
            app.TrainEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.TrainEditFieldLabel.Text = 'Train%';
            app.TrainEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.TrainEditField.Value = '70';
            app.ValidationEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.ValidationEditFieldLabel.Text = 'Validation%';
            app.ValidationEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.ValidationEditField.Value = '15';
            app.TestEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.TestEditFieldLabel.Text = 'Test%';
            app.TestEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.TestEditField.Editable = 'off';
            app.TestEditField.Value = '15';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.Text = 'Classify';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end